classdef ddos_track < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumberofDataSetsSliderLabel  matlab.ui.control.Label
        NumberofDataSetsSlider       matlab.ui.control.Slider
        DataTextAreaLabel            matlab.ui.control.Label
        DataTextArea                 matlab.ui.control.TextArea
        StartButton                  matlab.ui.control.Button
        LocationsTextAreaLabel       matlab.ui.control.Label
        LocationsTextArea            matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            drawnow
            app.DataTextArea.Value = 'Scraping...';
            % Scrape the url for source and destination addresses, write into array of structures containg the source address and destination address
            html = string(webread('https://liben002.github.io/delete.html'));
            pattern1 = '<Data Name="SourceAddress">';
            pattern2 = '</Data>';
            srcaddress = extractBetween(html, pattern1, pattern2);
            pattern1 = '<Data Name="DestAddress">';
            dstaddress = extractBetween(html, pattern1, pattern2);
            dat(1) = struct('src', srcaddress(1), 'dst', dstaddress(1));
            for i = 2:length(dstaddress)
                dat(i).src = srcaddress(i);
                dat(i).dst = dstaddress(i);
            end
            
            % Print out the source and destination addresses to the text area
            % updating each time and write the source and destination
            % addresses to a file
            file = fopen("ips.dat", "a");  
            val = sprintf('Writing to File...\nSource IP     Destination IP\n%s %s\n',dat(i).src,dat(i).src);
            app.DataTextArea.Value = val;   
            for i = 1:50
                val = sprintf('Writing to File...\nSource IP     Destination IP\n%s %s\n',dat(i).src,dat(i).dst);
                fprintf(file, "%s %s\n", dat(i).src, dat(i).dst);
                app.DataTextArea.Value = val;
                drawnow
            end
            app.DataTextArea.Value = 'Finished Writing to File';
            
            % Gets the state using the api and writes them to a file
            % Each key has 50k requests maximum. DO NOT USE THE FINALS.
            apiKey_Test = 'b68dbd3078324b7db7e03780158ab7a9';
            apiKey_Draft1 = '55772f4b051745228895b806ec1ed17c';
            apiKey_Draft2 = '24db145a81284c3bae2aa93915180569';
            apiKey_Final1 = '7e3b735fa5e14639a2c112a81e72e6e4';
            apiKey_Final2 = '2e3d7a5071b748c4a80792125c90dfa2';
            apiKey_Final3 = '2f1167b5e30b4a0db0a3c8bba9d9ce90';
            apiKey_Final4 = '';
            
            stateFID = fopen("countries.dat","a");
            val = 'Country Codes';
            for i = 1:50
                drawnow
                request = matlab.net.http.RequestMessage;
                URI = sprintf('https://api.ipgeolocation.io/ipgeo?apiKey=%s&ip=%s',apiKey_Test,dat(i).dst);
                uri = matlab.net.URI(URI);
                r = send(request,uri);
                
                % Display the locations while error checking.
                try
                    try
                        val = strcat(val,sprintf('\n%s',r.Body.Data.country_code2));
                        app.LocationsTextArea.Value = val;
                        fprintf(stateFID, "%s\n",r.Body.Data.country_code2);
                    catch
                        val = strcat(val,sprintf('\nPrivate'));
                        app.LocationsTextArea.Value = val;
                        fprintf(stateFID, "%s\n",'Private');
                    end
                catch
                    app.DataTextArea.Value = 'Could not write data to file';
                end
            end
            
            % Converts the min_max.c file to a mex file.
            try
                mex min_max.c
                min_max
            catch
                if isfile("min_max.c") ~= 1
                    disp('min_max.c missing')
                else
                    disp('Please run min_max.c manually')
                end
            end
            
            
            %for i = 1:app.NumberofDataSetsSlider.Value
                %request = matlab.net.http.RequestMessage;
                %uri = matlab.net.URI('http://api.ipstack.com/134.201.250.155?access_key=5363c21471a943a6941adf81243322b0');
                %r = send(request,uri);
                %disp(r.Body.Data.country_code)
            %end
            
            fclose('all');
        end

        % Callback function
        function DataTextAreaValueChanged(app, event)
            value = app.DataTextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofDataSetsSliderLabel = uilabel(app.UIFigure);
            app.NumberofDataSetsSliderLabel.Text = 'Number of Data Sets';
            app.NumberofDataSetsSlider = uislider(app.UIFigure);
            app.NumberofDataSetsSlider.Limits = [50000 100000];
            app.NumberofDataSetsSlider.Value = 100000;
            app.DataTextAreaLabel = uilabel(app.UIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.UIFigure);
            app.DataTextArea.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LocationsTextAreaLabel = uilabel(app.UIFigure);
            app.LocationsTextAreaLabel.Text = 'Locations';
            app.LocationsTextArea = uitextarea(app.UIFigure);
            app.LocationsTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ddos_track

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end